classdef simulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EndeffectorworktimesLabel  matlab.ui.control.Label
        ControllerSwitch           matlab.ui.control.Switch
        ControllerSwitchLabel      matlab.ui.control.Label
        Densitykgm3EditField       matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel  matlab.ui.control.Label
        PlotErrorButton            matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        Arm2LengthmEditField       matlab.ui.control.NumericEditField
        Arm2LengthmEditFieldLabel  matlab.ui.control.Label
        Arm1LengthmEditField       matlab.ui.control.NumericEditField
        Arm1LengthmEditFieldLabel  matlab.ui.control.Label
        SelectRobotLocationButton  matlab.ui.control.Button
        SimulationTimesEditField   matlab.ui.control.NumericEditField
        SimulationTimesLabel       matlab.ui.control.Label
        SelectEndEffectorLocationButton  matlab.ui.control.Button
        GoHomeButton               matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        endX;
        endY;
        robX; % last rob location for going home
        robY; 
        DCX; % desired rob location
        DCY;
        arm1;
        arm2;
        homeX = 7;
        homeY = 0;

        eeWorkTime = 0; % total time end effector able to work at desired location

        timeSim;
        wavesSim;
        volturnusSim;
    end
    
    methods (Access = private)
        
        function simulatorIK(app, t, eta, d, DC, plotData, IK )
            dt = t(2) - t(1);
            
            tVis = -5*dt:dt:t(end);
            swlVis = zeros(1,numel(tVis));
            etaVis = zeros(1,numel(tVis));
            start = numel(etaVis) - numel(eta) + 1;
            etaVis(start:end) = eta;
            if max(eta) == 0
                yLimit = 2.5;
            else
                yLimit = eta(eta==max(eta)) + 0.5;
            end
            
%             figure(app.UIFigure, 'units','normalized');

            % sea colour
            csea = [162, 232, 250]./255;

            for i = 1:numel(t)-9
                px = plotData.px(i);
                pz = plotData.pz(i);
                vx = plotData.vx(i);
                vz = plotData.vz(i);
                
%                 subplot(6,1,1)
                plot(app.UIAxes3, t, eta );
                hold(app.UIAxes3, 'on' );
                baseX = [ t(end)+1, t(1)-1 ];
                baseY = [ -yLimit-1, -yLimit-1 ];
                fill(app.UIAxes3, [t(1:end), baseX], [eta(1:end), baseY], csea, 'EdgeColor', 'b');
                hold(app.UIAxes3, 'off' );
                line(app.UIAxes3, [t(i),t(i)], [-10,10], 'Color', 'r' );
                if ~isempty(IK)
                    titles = strcat('Simulator at t =  ', num2str(tVis(i+5), '%.0f'), ' seconds.');
                else
                    titles = strcat('Going home at t =  ', num2str(tVis(i+5), '%.0f'), ' seconds.');
                end
                title(app.UIAxes3, titles);
                xlim(app.UIAxes3, [t(1), t(end)] );
                set(app.UIAxes3,'XTick',[]); 
%                 ylim(app.UIAxes3, [ -yLimit, yLimit ]);
%                 set(app.UIAxes3, 'YTickLabel',get(app.UIAxes3, 'YTickLabel'),'fontsize',16);  
                
%                 subplot(6,1,2)
                plot(app.UIAxes2, t, eta );
                plot(app.UIAxes2, tVis(i:i+5),etaVis(i:i+5), '-b', 'LineWidth', 4 ); 
                hold(app.UIAxes2, 'on' );
                plot(app.UIAxes2, tVis(i+5:i+10),etaVis(i+5:i+10), '--b', 'LineWidth', 4 );
                baseX = [ tVis(i+10)+1, tVis(i)-1 ];
                baseY = [ -yLimit-1, -yLimit-1 ];
                fill(app.UIAxes2, [tVis(i:i+10), baseX], [etaVis(i:i+10), baseY], csea, 'EdgeColor', 'None');
                plot(app.UIAxes2, tVis(i:i+10), swlVis(i:i+10), 'LineStyle', '-.' ); 
                hold(app.UIAxes2, 'off' );
                line(app.UIAxes2, [tVis(i+5),tVis(i+5)], [-10,10],'LineWidth', 2, 'Color', 'r' );
                %titles = strcat('Simulator at t =  ', num2str(tVis(i+5), '%.0f'), ' seconds.');
                %title(titles, 'FontSize', 24);
                xlim(app.UIAxes2, [tVis(i), tVis(i+10)] );
                set(app.UIAxes2, 'XTick',[]); 
%                 ylim(app.UIAxes2, [ -yLimit, yLimit ]);
%                 ylabel(app.UIAxes2, '                   Wave Amplitude, m', 'FontSize', 20);
%                 set(app.UIAxes2, 'YTickLabel',get(app.UIAxes2, 'YTickLabel'),'fontsize',16);  
                
%                 subplot(6,1,[3,4,5,6])
                plot(app.UIAxes, DC(1), DC(2), 'x', 'Color', '#51ad67', 'LineWidth', 1, 'Markers', 15 ); 
                hold(app.UIAxes, 'on' );
                plot(app.UIAxes, 7, 0, '^', 'Color', '#3d3c78', 'LineWidth', 2, MarkerSize=10)
                text(app.UIAxes, 7.5, 0, 'Home')
%                 set(app.UIAxes,'axis', 'equal')
                
                if ~isempty(IK) % only plot if IK is not empty
                    plot(app.UIAxes, IK.eeXY(1), IK.eeXY(2), 'or') % plot end effector desired location
    %                 link1 = plot(app.UIAxes, real(IK.linkA(i, [1 2])), real(IK.linkA(i, [3 4])), 'k-');
    %                 link2 = plot(app.UIAxes, real(IK.linkA(i, [5 6])), real(IK.linkA(i, [7 8])), 'k-');
    %                 link3 = plot(app.UIAxes, real(IK.linkB(i, [1 2])), real(IK.linkB(i, [3 4])), 'r-');
    %                 link4 = plot(app.UIAxes, real(IK.linkB(i, [5 6])), real(IK.linkB(i, [7 8])), 'r-');
                    if isreal(IK.linkA(i, :)) && isreal(IK.linkB(i, :)) % only plot link if is real
                        link1 = plot(app.UIAxes, IK.linkA(i, [1 2]), IK.linkA(i, [3 4]), 'k-');
                        link2 = plot(app.UIAxes, IK.linkA(i, [5 6]), IK.linkA(i, [7 8]), 'k-');
                        link3 = plot(app.UIAxes, IK.linkB(i, [1 2]), IK.linkB(i, [3 4]), 'r-');
                        link4 = plot(app.UIAxes, IK.linkB(i, [5 6]), IK.linkB(i, [7 8]), 'r-');
%                         app.eeWorkTime = app.eeWorkTime + dt; % if real add time increment for work
                    end
                end
                
                plot(app.UIAxes, px, pz, 'x', 'Color', '#51ad67', 'LineWidth', 4, 'Markers', 15 ); 
                % store end location for going home
                app.robX = px;
                app.robY = pz;

                axis(app.UIAxes, 'equal')
                quiver(app.UIAxes, px, pz, vx, vz, '-m', 'AutoScaleFactor', 2, 'LineWidth', 2, ...
                    'MaxHeadSize', 2 );
                hold(app.UIAxes, 'off' );
                axis(app.UIAxes, 'equal')
                ylim(app.UIAxes, [-10 0])
                xlim(app.UIAxes, [-10 10    ])
%                 axis(app.UIAxes, [-10, 10, DC(2)-5, DC(2)+5]); 
%                 grid(app.UIAxes, 'on' );
                xloc = strcat(' x =  ', num2str(px, '%.2f'), 'm, ');
                zloc = strcat(' y =  ', num2str(pz, '%.2f'), 'm');
                xlabel(app.UIAxes, strcat('Vehicle Displacement: ', xloc, zloc)); 
%                 ylabel(app.UIAxes, 'Water Depth, m', 'FontSize', 20);
%                 set(app.UIAxes, 'XTickLabel',get(app.UIAxes, 'XTickLabel'),'fontsize',16);
%                 set(app.UIAxes, 'YTickLabel',get(app.UIAxes, 'YTickLabel'),'fontsize',16);
                p = get(app.UIAxes, 'pos');
                p(4) = p(4) + 0.02;
                set(app.UIAxes, 'pos', p); set(app.UIAxes, 'color', csea);
                set(findobj('color','g'),'Color',[0 0.6 0]);
                line(app.UIAxes, [0,0], [(-1*d),0],'LineWidth', 2, 'Color', 'r' );

                app.EndeffectorworktimesLabel.Text = ['End-effector work time (s): ', num2str(app.eeWorkTime(i))];

                if i ~= 1
                    pause(.0005);
                else
                    pause(.05);
                end
                hold(app.UIAxes, 'off' );
            end
        end

        function [cirX, cirY] = circle(app,x,y,r)
            th = 0:pi/50:2*pi;
            cirX = r .* cos(th) + x;
            cirY = r .* sin(th) + y;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'equal')
            ylim(app.UIAxes, [-10 0])
            plot(app.UIAxes, 7, 0, '^', 'Color', '#3d3c78', 'LineWidth', 2, MarkerSize=10)
            text(app.UIAxes, 7.5, 0, 'Home')
            hold(app.UIAxes, 'on')
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            tic
            IC = [app.robX, app.robY, 0, 0, 0, 0]; %all initial conditions, pos, vel, acc (x and z)
            DC = [app.homeX, app.homeY, 0, 0, 0, 0]; %all desired conditions, pos, vel, acc (x and z)

            [ time ] = loadTimeParameters(20);
            t = time.t;
            
            waves = loadTempWaves(app.Densitykgm3EditField.Value);
            waves.swl = zeros(1, numel(time.t)); %still water line
            
            [ seaParticles, waves ] = getSeaStateParticles( time.t, IC(1), IC(2), waves );
            
            volturnus = loadBlueROV2Heavy( time.t, IC, DC, seaParticles );
            %[ dragForces ] = getDragForces( t, seaParticles, volturnus, waves.rho );
            
            counter = 1; 
            U = [ IC(1), IC(2), seaParticles.vx(1), seaParticles.vz(1), seaParticles.ax(1), seaParticles.az(1) ];
            [ volturnus.particlePlots ] = updatePlotHistory( U, volturnus.particlePlots, counter, 0 );
            % wavesOff = numel(time.t) - 180;
            wavesOff = 0;
%             if isequal(app.ControllerSwitch.Value, 'On')
                controllerOn = 1;% controller always on for going home
%             else
%                 controllerOn = 0;
%             end

            while counter ~= numel(time.t)
                if counter == controllerOn
                    while counter ~= numel(time.t)
                        [ waves ] = killWaves( time.t, waves, counter, wavesOff );
                        [ volturnus ] = pidMoveRobot( time.t, volturnus, waves, counter );
                        counter = counter + 1;
                    end
                    pErrorX = volturnus.errors.pErrorX;
                    pErrorZ = volturnus.errors.pErrorZ;
                    [ volturnus ] = updateErrors( volturnus, counter, pErrorX, pErrorZ );
                    break
                else
                    %[ waves ] = killWaves( time.t, waves, counter, wavesOff );
                    [ volturnus ] = driftMoveRobot( time.t, volturnus, waves, counter );
                    counter = counter + 1;
                end
            end
                 
            clear counter U pErrorX pErrorZ
            
            toc  

            % run simulator
            simulatorIK(app, time.t, waves.eta, waves.d, DC, volturnus.robotPlots, []);
        end

        % Button pushed function: SelectEndEffectorLocationButton
        function SelectEndEffectorLocationButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            [app.endX, app.endY] = ginput(1);                        % run ginput
            app.UIFigure.HandleVisibility = fhv;        % return original state
            plot(app.UIAxes, app.endX, app.endY, 'or') % plot end effector desired location
            hold(app.UIAxes, 'on' );
        end

        % Button pushed function: SelectRobotLocationButton
        function SelectRobotLocationButtonPushed(app, event)
            
            app.arm1 = app.Arm1LengthmEditField.Value;
            app.arm2 = app.Arm2LengthmEditField.Value;

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            [app.DCX, app.DCY] = ginput(1);                        % run ginput
            app.UIFigure.HandleVisibility = fhv;        % return original state
            plot(app.UIAxes, app.DCX, app.DCY, 'x', 'Color', '#51ad67', 'LineWidth', 1, 'Markers', 15 ) % plot end effector desired location 
            hold(app.UIAxes, 'on' );
            plot(app.UIAxes, 7, 0, '^', 'Color', '#3d3c78', 'LineWidth', 2, MarkerSize=10)
            text(app.UIAxes, 7.5, 0, 'Home')

            [cirX, cirY] = app.circle(app.DCX, app.DCY, app.arm1+app.arm2);
            plot(app.UIAxes, cirX, cirY, "Color", '#3c9955', 'LineWidth', 1)
%             hold(app.UIAxes, 'off' );
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.arm1 = app.Arm1LengthmEditField.Value;
            app.arm2 = app.Arm2LengthmEditField.Value;
            
            IC = [app.homeX, app.homeY, 0, 0, 0, 0]; %all initial conditions, pos, vel, acc (x and z)
            DC = [app.DCX, app.DCY, 0, 0, 0, 0]; %all desired conditions, pos, vel, acc (x and z)
            
            % end effector location
%             app.endX = -0.4;
%             app.endY = 0.5;
            % arm lengths
            l1 = app.arm1;
            l2 = app.arm2;
            % store in struct
            % IK.robotXY = [endX endY];
            if isempty(app.endX) || isempty(app.endY)
                f = errordlg('Please click the Select End-Effector button, and choose an end-effector location on screen.','No End-Effector Location Specified');
                pause;
            end
            IK.eeXY = [app.endX app.endY];
            IK.linkLength = [l1 l2];
            
            if isempty(app.SimulationTimesEditField)
                f = errordlg('Please enter a valid simulation time.','No Simulation Time Specified');
                pause;
            end
            [ time ] = loadTimeParameters(app.SimulationTimesEditField.Value);
            t = time.t;
            
            waves = loadTempWaves(app.Densitykgm3EditField.Value);
            waves.swl = zeros(1, numel(time.t)); %still water line
            
            [ seaParticles, waves ] = getSeaStateParticles( time.t, IC(1), IC(2), waves );
            
            volturnus = loadBlueROV2Heavy( time.t, IC, DC, seaParticles );
            %[ dragForces ] = getDragForces( t, seaParticles, volturnus, waves.rho );
            
            counter = 1; 
            U = [ IC(1), IC(2), seaParticles.vx(1), seaParticles.vz(1), seaParticles.ax(1), seaParticles.az(1) ];
            [ volturnus.particlePlots ] = updatePlotHistory( U, volturnus.particlePlots, counter, 0 );
            % wavesOff = numel(time.t) - 180;
            wavesOff = 0;
            if isequal(app.ControllerSwitch.Value, 'On')
                controllerOn = 1;%numel(time.t) - 149;
            else
                controllerOn = 0;
            end
            app.eeWorkTime = 0;
            while counter ~= numel(time.t)
                if counter == controllerOn
                    while counter ~= numel(time.t)
                        [ waves ] = killWaves( time.t, waves, counter, wavesOff );
                        [ IK.linkA(counter, :), IK.linkB(counter, :)] = robot2eeik([volturnus.state.px, volturnus.state.pz], IK.eeXY, IK.linkLength);
                        if isreal(IK.linkA(counter, :)) && isreal(IK.linkB(counter, :)) 
                            if counter == 1 % first time step no previous time step to add to
                                app.eeWorkTime(counter) = app.eeWorkTime(counter) + time.dt; % if real add time increment for work
                            else
                                app.eeWorkTime(counter) = app.eeWorkTime(counter-1) + time.dt; 
                            end
                        else
                            if counter == 1 % first time step no previous time step to add to
                                app.eeWorkTime(counter) = app.eeWorkTime(counter); % if real add time increment for work
                            else
                                app.eeWorkTime(counter) = app.eeWorkTime(counter-1); 
                            end
                        end
                        [ volturnus ] = pidMoveRobot( time.t, volturnus, waves, counter );
                        counter = counter + 1;
                    end
                    pErrorX = volturnus.errors.pErrorX;
                    pErrorZ = volturnus.errors.pErrorZ;
                    [ volturnus ] = updateErrors( volturnus, counter, pErrorX, pErrorZ );
                    break
                else
                    [ waves ] = killWaves( time.t, waves, counter, wavesOff );
                    [ IK.linkA(counter, :), IK.linkB(counter, :)] = robot2eeik([volturnus.state.px, volturnus.state.pz], IK.eeXY, IK.linkLength);
                    if isreal(IK.linkA(counter, :)) && isreal(IK.linkB(counter, :)) 
                        if counter == 1 % first time step no previous time step to add to
                            app.eeWorkTime(counter) = app.eeWorkTime(counter) + time.dt; % if real add time increment for work
                        else
                            app.eeWorkTime(counter) = app.eeWorkTime(counter-1) + time.dt; 
                        end
                    else
                        if counter == 1 % first time step no previous time step to add to
                            app.eeWorkTime(counter) = app.eeWorkTime(counter); % if real add time increment for work
                        else
                            app.eeWorkTime(counter) = app.eeWorkTime(counter-1); 
                        end
                    end
                    [ volturnus ] = driftMoveRobot( time.t, volturnus, waves, counter );
                    counter = counter + 1;
                end
            end
                 
            clear counter U pErrorX pErrorZ
            
            toc

            zero = zeros(length(time.t), 1);
            depthZero = DC(2) - zero;

            % save for plotting
            app.timeSim = time;
            app.wavesSim = waves;
            app.volturnusSim = volturnus;

            workTime = app.eeWorkTime;

            % save data
            dataName = ['RobotSim_', datestr(datetime, 'yymmddHHMM')];
            savePath = 'C:\Users\s2251764\OneDrive - University of Edinburgh\Y2\Robotics\assignment\data\';
            f = fopen([savePath, dataName, '.mat'], 'w');
            save([savePath, dataName, '.mat'], "time", "waves", "volturnus", "workTime")
            fclose(f);

            % run simulator
            simulatorIK(app, time.t, waves.eta, waves.d, DC, volturnus.robotPlots, IK );
        end

        % Button pushed function: PlotErrorButton
        function PlotErrorButtonPushed(app, event)
            figError = figure;

            zero = zeros(length(app.timeSim.t), 1);
%             depthZero = DC(2) - zero;

            x0=600;     % location of figure in the screen
            y0=200;     % location of figure in the screen
            width=600;  % width of figure
            height=500; % height of figure
            set(gcf,'position',[x0,y0,width,height])
            sgtitle('Positional Errors')
            ax = figError.CurrentAxes;
            subplot(3,1,1)
                plot(app.timeSim.t, app.wavesSim.eta, 'b', 'LineWidth', 1.5);
                xlim([min(app.timeSim.t) max(app.timeSim.t)]);
                ylim([min(app.wavesSim.eta)*1.2 max(app.wavesSim.eta)*1.2]);
                xlabel('Time (s)');
                ylabel('Wave Height (m)');
                hold on
                plot(app.timeSim.t, zero, 'r', 'LineWidth', 1.5)
                hold off
            subplot(3,1,2)
                plot(app.timeSim.t, app.volturnusSim.robotPlots.px-app.DCX, 'b', 'LineWidth', 1.5);
                xlim([min(app.timeSim.t) max(app.timeSim.t)]);
                %ylim([min(volturnus.robotPlots.px)*1.2 max(volturnus.robotPlots.px)*1.2]);
                xlabel('Time (s)');
                ylabel('Surge error (m)');
                hold on
                plot(app.timeSim.t, zero, 'r', 'LineWidth', 1.5)
                hold off
                %set(gca, 'Fontsize', 18);
                    
            subplot(3,1,3)
                plot(app.timeSim.t, app.volturnusSim.robotPlots.pz-app.DCY, 'b', 'LineWidth', 1.5);
                xlim([min(app.timeSim.t) max(app.timeSim.t)]);
                %ylim([min(volturnus.robotPlots.pz)*1.001 max(volturnus.robotPlots.pz)*0.999]);    
                xlabel('Time (s)');
                ylabel('Heave error (m)');
                hold on
                plot(app.timeSim.t, zero, 'r', 'LineWidth', 1.5)
                hold off
                %set(gca, 'Fontsize', 18);
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Vehicle Displacement (m)')
            ylabel(app.UIAxes, 'Water Depth (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 0];
            app.UIAxes.XTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0];
            app.UIAxes2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes2, 'Wave')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [-2 2];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Simulator')
            ylabel(app.UIAxes3, 'Amplitude (m)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.YLim = [-2 2];
            app.GoHomeButton = uibutton(app.UIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.SelectEndEffectorLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectEndEffectorLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEndEffectorLocationButtonPushed, true);
            app.SelectEndEffectorLocationButton.Text = {'Select '; 'End-Effector '; 'Location'};
            app.SimulationTimesLabel = uilabel(app.UIFigure);
            app.SimulationTimesLabel.Text = 'Simulation Time (s)';
            app.SimulationTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimesEditField.Value = 10;
            app.SelectRobotLocationButton = uibutton(app.UIFigure, 'push');
            app.SelectRobotLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRobotLocationButtonPushed, true);
            app.SelectRobotLocationButton.Text = {'Select '; 'Robot'; 'Location'};
            app.Arm1LengthmEditFieldLabel = uilabel(app.UIFigure);
            app.Arm1LengthmEditFieldLabel.Text = 'Arm1 Length (m)';
            app.Arm1LengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Arm1LengthmEditField.Value = 3;
            app.Arm2LengthmEditFieldLabel = uilabel(app.UIFigure);
            app.Arm2LengthmEditFieldLabel.Text = 'Arm2 Length (m)';
            app.Arm2LengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Arm2LengthmEditField.Value = 3;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlotErrorButton = uibutton(app.UIFigure, 'push');
            app.PlotErrorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotErrorButtonPushed, true);
            app.PlotErrorButton.Text = 'Plot Error';
            app.Densitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m^3)';
            app.Densitykgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Densitykgm3EditField.Value = 1025;
            app.ControllerSwitchLabel = uilabel(app.UIFigure);
            app.ControllerSwitchLabel.Text = 'Controller';
            app.ControllerSwitch = uiswitch(app.UIFigure, 'slider');
            app.EndeffectorworktimesLabel = uilabel(app.UIFigure);
            app.EndeffectorworktimesLabel.Text = 'End-effector work time (s): ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end